classdef Diagnosi_2_exoapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ServoFRButton  matlab.ui.control.Button
        GaugeFRButton  matlab.ui.control.Button
        ServoSRButton  matlab.ui.control.Button
        GaugeSRButton  matlab.ui.control.Button
        UIAxes2_3      matlab.ui.control.UIAxes
        UIAxes2_2      matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            var = load('identDL\var.mat');

%             XAGals=evalin('base','XAGals');
%             XB_hat_Gaus=evalin('base','XB_hat_Gaus');

            plot(app.UIAxes2_3,var.XAGals, 'Color', 'k', 'LineWidth',3);
            hold (app.UIAxes2_3, 'on');
            plot(app.UIAxes2_3,1:numel(var.XB_hat_Gaus), var.XB_hat_Gaus, 'Color', 'b', 'LineWidth',2);
            legend(app.UIAxes2_3,{'Input Signal' 'Reconstructed Signal'});
            title(app.UIAxes2_3,'Reconstruction Gauge SlowRate');
            grid (app.UIAxes2_3,'on');
            
%             EBGaus=evalin('base','EBGaus');
%             MskGs=evalin('base','MskGs');


            hold (app.UIAxes2,'on');
            plot(app.UIAxes2,var.EBGaus, 'Color', 'blue','LineWidth',3);
            plot(app.UIAxes2,var.MskGs(1,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2,'off');
            title(app.UIAxes2,'Error');
            grid (app.UIAxes2,'on');
            
            hold (app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,var.XAGals, 'Color', 'k', 'LineWidth',3);
            plot(app.UIAxes2_2,var.MskGs(2,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2_2,'off');
            legend(app.UIAxes2_2,{'Input Signal' 'Reconstructed Signal' 'Anomaly'})
            title(app.UIAxes2_2,'Anomaly detector');
            grid (app.UIAxes2_2,'on');


        end

        % Button pushed function: GaugeSRButton
        function GaugeSRButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
             var = load('identDL\var.mat');

            plot(app.UIAxes2_3,var.XAGals, 'Color', 'k', 'LineWidth',3);
            hold (app.UIAxes2_3, 'on');
            plot(app.UIAxes2_3,1:numel(var.XB_hat_Gaus), var.XB_hat_Gaus, 'Color', 'b', 'LineWidth',2);
            legend(app.UIAxes2_3,{'Input Signal' 'Reconstructed Signal'});
            title(app.UIAxes2_3,'Reconstruction Gauge SlowRate');
            grid (app.UIAxes2_3,'on');

            hold (app.UIAxes2,'on');
            plot(app.UIAxes2,var.EBGaus, 'Color', 'blue','LineWidth',3);
            plot(app.UIAxes2,var.MskGs(1,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2,'off');
            title(app.UIAxes2,'Error');
            grid (app.UIAxes2,'on');
            
            hold (app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,var.XAGals, 'Color', 'k', 'LineWidth',3);
            plot(app.UIAxes2_2,var.MskGs(2,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2_2,'off');
            legend(app.UIAxes2_2,{'Input Signal' 'Reconstructed Signal' 'Anomaly'})
            title(app.UIAxes2_2,'Anomaly detector');
            grid (app.UIAxes2_2,'on');
        end

        % Button pushed function: ServoSRButton
        function ServoSRButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
             var = load('identDL\var.mat');

            plot(app.UIAxes2_3,var.XAServs, 'Color', 'k', 'LineWidth',3);
            hold (app.UIAxes2_3, 'on');
            plot(app.UIAxes2_3,1:numel(var.XB_hat_Servs), var.XB_hat_Servs, 'Color', 'b', 'LineWidth',2);
            legend(app.UIAxes2_3,{'Input Signal' 'Reconstructed Signal'});
            title(app.UIAxes2_3,'Reconstruction Servo SlowRate');
            grid (app.UIAxes2_3,'on');

            hold (app.UIAxes2,'on');
            plot(app.UIAxes2,var.EBServs, 'Color', 'blue','LineWidth',3);
            plot(app.UIAxes2,var.MskSs(1,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2,'off');
            title(app.UIAxes2,'Error');
            grid (app.UIAxes2,'on');
            
            hold (app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,var.XAServs, 'Color', 'k', 'LineWidth',3);
            plot(app.UIAxes2_2,var.MskSs(2,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2_2,'off');
            legend(app.UIAxes2_2,{'Input Signal' 'Reconstructed Signal' 'Anomaly'})
            title(app.UIAxes2_2,'Anomaly detector');
            grid (app.UIAxes2_2,'on');
        end

        % Button pushed function: GaugeFRButton
        function GaugeFRButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            var = load('identDL\var.mat');

            plot(app.UIAxes2_3,var.XAGalf, 'Color', 'k', 'LineWidth',3);
            hold (app.UIAxes2_3, 'on');
            plot(app.UIAxes2_3,1:numel(var.XB_hat_Gauf), var.XB_hat_Gauf, 'Color', 'b', 'LineWidth',2);
            legend(app.UIAxes2_3,{'Input Signal' 'Reconstructed Signal'});
            title(app.UIAxes2_3,'Reconstruction Gauge FastRate');
            grid (app.UIAxes2_3,'on');

            hold (app.UIAxes2,'on');
            plot(app.UIAxes2,var.EBGauf, 'Color', 'blue','LineWidth',3);
            plot(app.UIAxes2,var.MskGf(1,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2,'off');
            title(app.UIAxes2,'Error');
            grid (app.UIAxes2,'on');
            
            hold (app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,var.XAGalf, 'Color', 'k', 'LineWidth',3);
            plot(app.UIAxes2_2,var.MskGf(2,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2_2,'off');
            legend(app.UIAxes2_2,{'Input Signal' 'Reconstructed Signal' 'Anomaly'})
            title(app.UIAxes2_2,'Anomaly detector');
            grid (app.UIAxes2_2,'on');
        end

        % Button pushed function: ServoFRButton
        function ServoFRButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            var = load('identDL\var.mat');

            plot(app.UIAxes2_3,var.XAServf, 'Color', 'k', 'LineWidth',3);
            hold (app.UIAxes2_3, 'on');
            plot(app.UIAxes2_3,1:numel(var.XB_hat_Servf), var.XB_hat_Servf, 'Color', 'b', 'LineWidth',2);
            legend(app.UIAxes2_3,{'Input Signal' 'Reconstructed Signal'});
            title(app.UIAxes2_3,'Reconstruction Servo FastRate');
            grid (app.UIAxes2_3,'on');

            hold (app.UIAxes2,'on');
            plot(app.UIAxes2,var.EBServf, 'Color', 'blue','LineWidth',3);
            plot(app.UIAxes2,var.MskSf(1,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2,'off');
            title(app.UIAxes2,'Error');
            grid (app.UIAxes2,'on');
            
            hold (app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,var.XAServf, 'Color', 'k', 'LineWidth',3);
            plot(app.UIAxes2_2,var.MskSf(2,:), 'Color', 'red', 'LineWidth',3);
            hold (app.UIAxes2_2,'off');
            legend(app.UIAxes2_2,{'Input Signal' 'Reconstructed Signal' 'Anomaly'})
            title(app.UIAxes2_2,'Anomaly detector');
            grid (app.UIAxes2_2,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Position (V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'Position (V)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'Time (s)')
            ylabel(app.UIAxes2_3, 'Position (V)')
            zlabel(app.UIAxes2_3, 'Z')
            app.GaugeSRButton = uibutton(app.UIFigure, 'push');
            app.GaugeSRButton.ButtonPushedFcn = createCallbackFcn(app, @GaugeSRButtonPushed, true);
            app.GaugeSRButton.Text = 'Gauge SR';
            app.ServoSRButton = uibutton(app.UIFigure, 'push');
            app.ServoSRButton.ButtonPushedFcn = createCallbackFcn(app, @ServoSRButtonPushed, true);
            app.ServoSRButton.Text = 'Servo SR';
            app.GaugeFRButton = uibutton(app.UIFigure, 'push');
            app.GaugeFRButton.ButtonPushedFcn = createCallbackFcn(app, @GaugeFRButtonPushed, true);
            app.GaugeFRButton.Text = 'Gauge FR';
            app.ServoFRButton = uibutton(app.UIFigure, 'push');
            app.ServoFRButton.ButtonPushedFcn = createCallbackFcn(app, @ServoFRButtonPushed, true);
            app.ServoFRButton.Text = 'Servo FR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diagnosi_2_exoapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end