classdef Therapy_1_exoapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EXOAPPUIFigure                matlab.ui.Figure
        IdentificationEditField       matlab.ui.control.EditField
        IdentificationEditFieldLabel  matlab.ui.control.Label
        HealthyPatientCheckBox        matlab.ui.control.CheckBox
        DropDown                      matlab.ui.control.DropDown
        Spinner_2                     matlab.ui.control.Spinner
        Spinner                       matlab.ui.control.Spinner
        ExitButton                    matlab.ui.control.Button
        StartTherapyButton            matlab.ui.control.Button
        WeightLabel                   matlab.ui.control.Label
        SexLabel                      matlab.ui.control.Label
        AgeLabel                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: StartTherapyButton
        function StartTherapyButtonPushed(app, event)
 %% Assignació de valors obtinguts a la app, per treballar amb la informació al WorkSpace
            id   = app.IdentificationEditField.Value;
            edad = app.Spinner.Value;
            sexo = app.DropDown.Value;
            peso = app.Spinner_2.Value;
            sano = app.HealthyPatientCheckBox.Value;
 %% Creació del nom per guardar l'arxiu
            comando = strcat('save'," ","OutputData\Terapia_"+ id + datestr(now, '_yyyy-mm-dd_HH-MM'));
 %% Assignació del nom de les variables            
            assignin('base','ID',comando);
            assignin('base','id',strcat(id));
            assignin('base','sexo',strcat(sexo));
            assignin('base','edad',int2str(edad));
            assignin('base','peso',int2str(peso));
            assignin('base','sano',int2str(sano));
            Therapy_2_exoapp;
            delete(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
           
        end

        % Value changed function: HealthyPatientCheckBox
        function HealthyPatientCheckBoxValueChanged(app, event)

            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EXOAPPUIFigure = uifigure('Visible', 'off');
            app.EXOAPPUIFigure.Name = 'EXOAPP';
            app.EXOAPPUIFigure.Icon = fullfile(pathToMLAPP, 'hand.png');
            app.Label = uilabel(app.EXOAPPUIFigure);
            app.Label.Text = 'Enter de following data to perform the therapy';
            app.AgeLabel = uilabel(app.EXOAPPUIFigure);
            app.AgeLabel.Text = 'Age:';
            app.SexLabel = uilabel(app.EXOAPPUIFigure);
            app.SexLabel.Text = {'Sex:'; ''};
            app.WeightLabel = uilabel(app.EXOAPPUIFigure);
            app.WeightLabel.Text = 'Weight:';
            app.StartTherapyButton = uibutton(app.EXOAPPUIFigure, 'push');
            app.StartTherapyButton.ButtonPushedFcn = createCallbackFcn(app, @StartTherapyButtonPushed, true);
            app.StartTherapyButton.Text = 'Start Therapy';
            app.ExitButton = uibutton(app.EXOAPPUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Spinner = uispinner(app.EXOAPPUIFigure);
            app.Spinner.Limits = [0 199];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner_2 = uispinner(app.EXOAPPUIFigure);
            app.Spinner_2.Limits = [0 200];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.DropDown = uidropdown(app.EXOAPPUIFigure);
            app.DropDown.Items = {'Male', 'Female', 'Other'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Male';
            app.HealthyPatientCheckBox = uicheckbox(app.EXOAPPUIFigure);
            app.HealthyPatientCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthyPatientCheckBoxValueChanged, true);
            app.HealthyPatientCheckBox.Text = 'Healthy Patient';
            app.IdentificationEditFieldLabel = uilabel(app.EXOAPPUIFigure);
            app.IdentificationEditFieldLabel.Text = 'Identification:';
            app.IdentificationEditField = uieditfield(app.EXOAPPUIFigure, 'text');
            % Show the figure after all components are created
            app.EXOAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Therapy_1_exoapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EXOAPPUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EXOAPPUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EXOAPPUIFigure)
        end
    end
end