classdef Therapy_2_exoapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EXOAPPUIFigure      matlab.ui.Figure
        EXITSAVEDATAButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        datosg
        datoss
        tiempo
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        ylim(app.UIAxes,[-75,75]);
        ylim(app.UIAxes_2,[-45,110]); 

            a = arduino("COM4","Uno","BaudRate",115200);
            galga = 'A3';
            servo = 'A0';
            g=1;
            muestras=20000;
            Fs=0.01;
%             load("F:\documentos TFG\archivos matlab\registroBD\BD.mat",'k');
           
% bucle
        for i=1:muestras           
                lectg = readVoltage(a,galga);
                lects = readVoltage(a,servo);
    
                dataGau(i)=lectg*250 -475;
                dataServ(i)=lects*94-80;
                
                datosg(g)=lectg;
                datoss(g)=lects*2/100-810;

            tiempo=0.01:Fs:i*Fs;
      
            xlim(app.UIAxes,[i*Fs-1,g*Fs+1]);
            xlim(app.UIAxes_2,[i*Fs-1,g*Fs+1]);
            g=g+1;
      
            plot(app.UIAxes,tiempo,dataGau,'Color', 'k', 'LineWidth',3');
            plot(app.UIAxes_2,tiempo,dataServ,'Color', 'k', 'LineWidth',3');

                assignin('base','datosg',datosg);
                app.datosg=datosg;
                assignin('base','datoss',datoss);
                app.datoss=datoss;


%             if mod(i,20)
%             assignin('base','dataGau  ',datosg(:));
%             assignin('base','dataServ',datoss(:));
%             assignin('base','tiempo',tiempo(:));
%             end
        end


        end

        % Button pushed function: EXITSAVEDATAButton
        function EXITSAVEDATAButtonPushed(app, event)

%           datosg=app.datosg; 
%           datoss=app.datoss; 

            run saveTherData.m;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EXOAPPUIFigure = uifigure('Visible', 'off');
            app.EXOAPPUIFigure.Name = 'EXOAPP';
            app.EXOAPPUIFigure.Icon = fullfile(pathToMLAPP, 'hand.png');
            app.UIAxes_2 = uiaxes(app.EXOAPPUIFigure);
            title(app.UIAxes_2, 'Servomotor')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Angle')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.EXOAPPUIFigure);
            title(app.UIAxes, 'Straing Cell')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Deformation')
            zlabel(app.UIAxes, 'Z')
            app.EXITSAVEDATAButton = uibutton(app.EXOAPPUIFigure, 'push');
            app.EXITSAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @EXITSAVEDATAButtonPushed, true);
            app.EXITSAVEDATAButton.Text = 'EXIT & SAVE DATA';
            % Show the figure after all components are created
            app.EXOAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Therapy_2_exoapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EXOAPPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EXOAPPUIFigure)
        end
    end
end